classdef gui3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MENUPanel                       matlab.ui.container.Panel
        EdgeDetectionAlgorithmDropDown  matlab.ui.control.DropDown
        EdgeDetectionAlgorithmDropDownLabel  matlab.ui.control.Label
        RUNButton                       matlab.ui.control.Button
        FilenameLabel                   matlab.ui.control.Label
        FileinputLabel                  matlab.ui.control.Label
        UploadButton                    matlab.ui.control.Button
        TUCIL3IF4073Label               matlab.ui.control.Label
        SegmentationMaskAxes            matlab.ui.control.UIAxes
        ObjectSegmentationAxes          matlab.ui.control.UIAxes
        EdgeImageAxes                   matlab.ui.control.UIAxes
        OriginalImageAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ImageUploaded % uploaded image
        IsGrayscale % is the uploaded image grayscale or not
        EdgeImage % output from edge detection algorithm
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(app.OriginalImageAxes, 'visible', 'off')
            set(app.EdgeImageAxes, 'visible', 'off')
            set(app.SegmentationMaskAxes, 'visible', 'off')
            set(app.ObjectSegmentationAxes, 'visible', 'off')
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            % read image file
            [filename,filepath] = uigetfile({'*.jpg;*.tif;*.png;*.gif;*.bmp'}, 'Select File to Open');
            if ischar(filepath)
                fullname = [filepath, filename];
                app.ImageUploaded= imread(fullname);
    
                % get image type (grayscale/ rgb)
                app.IsGrayscale = Helper.isGrayscale(app.ImageUploaded);
    
                % show image
                set(app.OriginalImageAxes, 'visible', 'on')
                imshow(app.ImageUploaded, 'Parent', app.OriginalImageAxes)
            end
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            % check selected edge detection algorithm
            edgedetection = app.EdgeDetectionAlgorithmDropDown.Value;

            % detect edge
            switch edgedetection
                case "Laplace"
                    % app.EdgeImage = blabla
                case "LoG"
                case "Sobel"
                case "Prewitt"
                case "Roberts"
                case "Canny"
            end

            % show edge image
            imshow(app.EdgeImage, 'Parent', app.EdgeImageAxes)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OriginalImageAxes = uiaxes(app.UIFigure);
            title(app.OriginalImageAxes, 'ORIGINAL IMAGE')
            app.OriginalImageAxes.XTickLabel = '';
            app.OriginalImageAxes.YTickLabel = '';
            app.EdgeImageAxes = uiaxes(app.UIFigure);
            title(app.EdgeImageAxes, 'EDGE IMAGE')
            app.EdgeImageAxes.XTickLabel = '';
            app.EdgeImageAxes.YTickLabel = '';
            app.ObjectSegmentationAxes = uiaxes(app.UIFigure);
            title(app.ObjectSegmentationAxes, 'OBJECT SEGMENTATION')
            app.ObjectSegmentationAxes.XTickLabel = '';
            app.ObjectSegmentationAxes.YTickLabel = '';
            app.SegmentationMaskAxes = uiaxes(app.UIFigure);
            title(app.SegmentationMaskAxes, 'SEGMENTATION MASK')
            app.SegmentationMaskAxes.XTickLabel = '';
            app.SegmentationMaskAxes.YTickLabel = '';
            app.TUCIL3IF4073Label = uilabel(app.UIFigure);
            app.TUCIL3IF4073Label.Text = 'TUCIL 3 IF4073';
            app.UploadButton = uibutton(app.UIFigure, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.FileinputLabel = uilabel(app.UIFigure);
            app.FileinputLabel.Text = 'File input';
            app.FilenameLabel = uilabel(app.UIFigure);
            app.FilenameLabel.Text = '';
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.MENUPanel = uipanel(app.UIFigure);
            app.MENUPanel.Title = 'MENU';
            app.EdgeDetectionAlgorithmDropDownLabel = uilabel(app.MENUPanel);
            app.EdgeDetectionAlgorithmDropDownLabel.Text = 'Edge Detection Algorithm';
            app.EdgeDetectionAlgorithmDropDown = uidropdown(app.MENUPanel);
            app.EdgeDetectionAlgorithmDropDown.Items = {'Laplace', 'LoG', 'Sobel', 'Prewitt', 'Roberts', 'Canny'};
            app.EdgeDetectionAlgorithmDropDown.Value = 'Laplace';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end